classdef PGZ_decoder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        infoEditField        matlab.ui.control.EditField
        infoEditFieldLabel   matlab.ui.control.Label
        cdecEditField        matlab.ui.control.EditField
        cdecEditFieldLabel   matlab.ui.control.Label
        eEditField           matlab.ui.control.EditField
        eEditFieldLabel      matlab.ui.control.Label
        cEditField           matlab.ui.control.EditField
        cEditFieldLabel      matlab.ui.control.Label
        SeedeEditField       matlab.ui.control.NumericEditField
        SeedeEditFieldLabel  matlab.ui.control.Label
        SeedcEditField       matlab.ui.control.NumericEditField
        SeedcEditFieldLabel  matlab.ui.control.Label
        NEditField           matlab.ui.control.NumericEditField
        NEditFieldLabel      matlab.ui.control.Label
        dEditField           matlab.ui.control.NumericEditField
        dEditFieldLabel      matlab.ui.control.Label
        LetsgoButton         matlab.ui.control.Button
        qEditField           matlab.ui.control.NumericEditField
        qEditFieldLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        GenerateButton    matlab.ui.control.Button
        SeedEditField     matlab.ui.control.NumericEditField
        SeedEditFieldLabel matlab.ui.control.Label
        NEditField        matlab.ui.control.NumericEditField
        NEditFieldLabel   matlab.ui.control.Label
        dEditField        matlab.ui.control.NumericEditField
        dEditFieldLabel   matlab.ui.control.Label
        qEditField        matlab.ui.control.NumericEditField
        qEditFieldLabel   matlab.ui.control.Label
        InfoTextArea      matlab.ui.control.TextArea
        CodedTextArea     matlab.ui.control.TextArea
        ErrorTextArea     matlab.ui.control.TextArea
        DecodedTextArea   matlab.ui.control.TextArea
    end
    
    methods (Access = private)
        
        function results = generateRSCode(app)
            q = app.qEditField.Value;
            d = app.dEditField.Value;
            N = app.NEditField.Value;
            seed = app.SeedEditField.Value;
            
            rng(seed);
            m = ceil(log2(q)); % Length of symbol in bits
            n = 2^m - 1; % Code block length
            k = n - 2*d; % Message length

            % Create Reed-Solomon encoder and decoder
            enc = comm.RSEncoder(n, k);
            dec = comm.RSDecoder(n, k);

            % Generate random information vector
            info = randi([0 q-1], k, 1);
            app.InfoTextArea.Value = num2str(info);

            % Encode information
            coded = step(enc, info);
            app.CodedTextArea.Value = num2str(coded);

            % Introduce errors
            errorIndices = randperm(n, N);
            errorVector = zeros(n, 1);
            errorVector(errorIndices) = randi([1 q-1], length(errorIndices), 1);
            noisyCoded = mod(coded + errorVector, q);
            app.ErrorTextArea.Value = num2str(errorVector);

            % Decode
            decoded = step(dec, noisyCoded);
            app.DecodedTextArea.Value = num2str(decoded);

            results = struct('info', info, 'coded', coded, 'errors', errorVector, 'decoded', decoded);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsgoButton
        function LetsgoButtonPushed(app, event)
            q = app.qEditField.Value;
            d = app.dEditField.Value;
            N = app.NEditField.Value;
            seede = app.SeedeEditField.Value;
            seedc = app.SeedcEditField.Value;

            m = ceil(log2(q)); % Length of symbol in bits
            n = 2^m - 1; % Code block length
            k = n - 2*d; % Message length

            % Create Reed-Solomon encoder and decoder
            enc = comm.RSEncoder(n, k);
            dec = comm.RSDecoder(n, k);

            % Generate random information vector
            rng(seedc);
            info = randi([0 q-1], k, 1);
            app.infoEditField.Value = strjoin(string(info), ', ');

            % Encode information
            coded = step(enc, info);
            app.cEditField.Value = strjoin(string(coded), ', ');

            % Introduce errors
            rng(seede);
            errorIndices = randperm(n, N);
            errorVector = zeros(n, 1);
            errorVector(errorIndices) = randi([1 q-1], length(errorIndices), 1);
            noisyCoded = mod(coded + errorVector, q);
            app.eEditField.Value = strjoin(string(errorVector), ', ');

            % Decode
            decoded = step(dec, noisyCoded);
            app.cdecEditField.Value = strjoin(string(decoded), ', ');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.qEditFieldLabel = uilabel(app.UIFigure);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.UIFigure, 'numeric');
            app.qEditField.Value = 16;
            app.LetsgoButton = uibutton(app.UIFigure, 'push');
            app.LetsgoButton.ButtonPushedFcn = createCallbackFcn(app, @LetsgoButtonPushed, true);
            app.LetsgoButton.Text = 'Lets go';
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.Value = 1;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 1;
            app.SeedcEditFieldLabel = uilabel(app.UIFigure);
            app.SeedcEditFieldLabel.Text = 'Seed c';
            app.SeedcEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedcEditField.Value = 42;
            app.SeedeEditFieldLabel = uilabel(app.UIFigure);
            app.SeedeEditFieldLabel.Text = 'Seed e';
            app.SeedeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedeEditField.Value = 42;
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'text');
            app.eEditFieldLabel = uilabel(app.UIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.UIFigure, 'text');
            app.cdecEditFieldLabel = uilabel(app.UIFigure);
            app.cdecEditFieldLabel.Text = 'c dec';
            app.cdecEditField = uieditfield(app.UIFigure, 'text');
            app.infoEditFieldLabel = uilabel(app.UIFigure);
            app.infoEditFieldLabel.Text = 'info';
            app.infoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PGZ_decoder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end